classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DetailsTab                      matlab.ui.container.Tab
        BioSimLabel                     matlab.ui.control.Label
        NumberofPeopleEditFieldLabel    matlab.ui.control.Label
        NumberofPeopleEditField         matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        InitialPressurekPaEditFieldLabel  matlab.ui.control.Label
        InitialPressurekPaEditField     matlab.ui.control.NumericEditField
        AirTemperatureCEditFieldLabel   matlab.ui.control.Label
        AirTemperatureCEditField        matlab.ui.control.NumericEditField
        OutsideTemperatureCEditFieldLabel  matlab.ui.control.Label
        OutsideTemperatureCEditField    matlab.ui.control.NumericEditField
        RelativeHumidityEditFieldLabel  matlab.ui.control.Label
        RelativeHumidityEditField       matlab.ui.control.NumericEditField
        TimeStepdtButtonGroup           matlab.ui.container.ButtonGroup
        MinuteButton                    matlab.ui.control.RadioButton
        HourButton                      matlab.ui.control.RadioButton
        TemperatureTab                  matlab.ui.container.Tab
        UITemperatureAxes               matlab.ui.control.UIAxes
        StepButton1h                    matlab.ui.control.Button
        StepButton1d                    matlab.ui.control.Button
        StepButton2w                    matlab.ui.control.Button
        StepButton2d                    matlab.ui.control.Button
        StepButton2h                    matlab.ui.control.Button
        StepButton1w                    matlab.ui.control.Button
        StepButton3w                    matlab.ui.control.Button
        StepButton3d                    matlab.ui.control.Button
        StepButton3h                    matlab.ui.control.Button
        CropTab                         matlab.ui.container.Tab
        UICropAxes                      matlab.ui.control.UIAxes
        WaterTab                        matlab.ui.container.Tab
        UIWaterAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        m                               % Model handle
    end
    
    methods (Access = private)
        
        function PlotVars(app)
            if isempty(app.m.hours)
                return
            end
            
            % Collect variables for plotting
            % Temperature variables
            Ta = app.m.trackTa;
            Tw = app.m.trackTw;
            
            plot(app.UITemperatureAxes, app.m.hours, Ta, app.m.hours, Tw);
            legend(app.UITemperatureAxes, ["Air", "Water"]);
            xlim(app.UITemperatureAxes,[1 max(app.m.hours)]);
            
            % Crop variables
            trackLeafB=app.m.trackLeafB/1000;
            trackStorB=app.m.trackStorB/1000;
            trackStemB=app.m.trackStemB/1000;
            trackRootB=app.m.trackRootB/1000;
            
            plot(app.UICropAxes, app.m.hours, trackLeafB, app.m.hours, trackStemB, app.m.hours,...
                trackRootB, app.m.hours, trackStorB);
            legend(app.UICropAxes,["Leaf", "Stem", "Roots", "Storage"]);
            xlim(app.UICropAxes,[1 max(app.m.hours)]);
            
            % Water variables
            trackVcrop=app.m.trackVcrop;
            trackVwetland=app.m.trackVwetland;
            trackVvapor=app.m.trackVvapor;
            trackVstore=app.m.trackVstore;
            trackVsed=app.m.trackVsed;
            trackVreject=app.m.trackVreject;
            
            plot(app.UIWaterAxes, app.m.hours, trackVstore, app.m.hours, trackVvapor, app.m.hours,...
                trackVcrop, app.m.hours, trackVsed, app.m.hours, trackVwetland, app.m.hours, trackVreject);
            legend(app.UIWaterAxes,["Storage","Air","Crop","Sed. Basin","Wetland","Reject"]);
            xlim(app.UIWaterAxes,[1 max(app.m.hours)]);
        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Disable Start button to prevent action being called twice.
            app.StartButton.Enable = 'off';
            app.NumberofPeopleEditField.Enable = 'off';
            app.AirTemperatureCEditField.Enable = 'off';
            app.InitialPressurekPaEditField.Enable = 'off';
            app.OutsideTemperatureCEditField.Enable = 'off';
            app.RelativeHumidityEditField.Enable = 'off';
            app.MinuteButton.Enable = 'off';
            app.HourButton.Enable = 'off';
            
            % Initialize model paths
            addpath blocks;
            addpath data;
            addpath eqn;
            
            % Initialize variables from app
            no_people = app.NumberofPeopleEditField.Value;
            P = app.InitialPressurekPaEditField.Value;
            Ta = app.AirTemperatureCEditField.Value;
            To = app.OutsideTemperatureCEditField.Value;
            RH = app.RelativeHumidityEditField.Value/100;
            
            if app.MinuteButton.Value == 1
                dt = time.MINUTE;
            else
                dt = time.HOUR;
            end
            
            % Create the model
            app.m = model(no_people, P, Ta, To, RH, dt);
            
            % Initialize Play/Step buttons
            app.StepButton1h.Enable = 'on';
            app.StepButton1d.Enable = 'on';
            app.StepButton1w.Enable = 'on';
            
            app.StepButton2h.Enable = 'on';
            app.StepButton2d.Enable = 'on';
            app.StepButton2w.Enable = 'on';
            
            app.StepButton3h.Enable = 'on';
            app.StepButton3d.Enable = 'on';
            app.StepButton3w.Enable = 'on';
        end

        % Button pushed function: StepButton1h, StepButton2h, 
        % StepButton3h
        function StepHourButtonPushed(app, event)
            app.m.step;
            app.PlotVars;
        end

        % Button pushed function: StepButton1d, StepButton2d, 
        % StepButton3d
        function StepDayButtonPushed(app, event)
            for i = 1:24
                app.m.step;
            end
            app.PlotVars;
        end

        % Button pushed function: StepButton1w, StepButton2w, 
        % StepButton3w
        function StepWeekButtonPushed(app, event)
            for i = 1:168
                app.m.step;
            end
            app.PlotVars;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.BioSimLabel = uilabel(app.DetailsTab);
            app.BioSimLabel.Text = 'BioSim';
            app.NumberofPeopleEditFieldLabel = uilabel(app.DetailsTab);
            app.NumberofPeopleEditFieldLabel.Text = 'Number of People';
            app.NumberofPeopleEditField = uieditfield(app.DetailsTab, 'numeric');
            app.NumberofPeopleEditField.Limits = [1 50];
            app.NumberofPeopleEditField.Value = 8;
            app.StartButton = uibutton(app.DetailsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InitialPressurekPaEditFieldLabel = uilabel(app.DetailsTab);
            app.InitialPressurekPaEditFieldLabel.Text = 'Initial Pressure (kPa)';
            app.InitialPressurekPaEditField = uieditfield(app.DetailsTab, 'numeric');
            app.InitialPressurekPaEditField.Limits = [20 120];
            app.InitialPressurekPaEditField.Value = 101.325;
            app.AirTemperatureCEditFieldLabel = uilabel(app.DetailsTab);
            app.AirTemperatureCEditFieldLabel.Text = 'Air Temperature (C)';
            app.AirTemperatureCEditField = uieditfield(app.DetailsTab, 'numeric');
            app.AirTemperatureCEditField.Limits = [0 40];
            app.AirTemperatureCEditField.Value = 20;
            app.OutsideTemperatureCEditFieldLabel = uilabel(app.DetailsTab);
            app.OutsideTemperatureCEditFieldLabel.Text = 'Outside Temperature (C)';
            app.OutsideTemperatureCEditField = uieditfield(app.DetailsTab, 'numeric');
            app.OutsideTemperatureCEditField.Limits = [0 40];
            app.OutsideTemperatureCEditField.Value = 20;
            app.RelativeHumidityEditFieldLabel = uilabel(app.DetailsTab);
            app.RelativeHumidityEditFieldLabel.Text = 'Relative Humidity (%)';
            app.RelativeHumidityEditField = uieditfield(app.DetailsTab, 'numeric');
            app.RelativeHumidityEditField.Limits = [0 100];
            app.RelativeHumidityEditField.Value = 50;
            app.TimeStepdtButtonGroup = uibuttongroup(app.DetailsTab);
            app.TimeStepdtButtonGroup.Title = 'Time Step (dt)';
            app.MinuteButton = uiradiobutton(app.TimeStepdtButtonGroup);
            app.MinuteButton.Text = 'Minute';
            app.HourButton = uiradiobutton(app.TimeStepdtButtonGroup);
            app.HourButton.Text = 'Hour';
            app.HourButton.Value = true;
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.UITemperatureAxes = uiaxes(app.TemperatureTab);
            title(app.UITemperatureAxes, 'Temperature Gauge')
            xlabel(app.UITemperatureAxes, 'Hour')
            ylabel(app.UITemperatureAxes, 'Temperature (C)')
            app.StepButton1h = uibutton(app.TemperatureTab, 'push');
            app.StepButton1h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton1h.Enable = 'off';
            app.StepButton1h.Text = {'>'; '1 hr'};
            app.StepButton1d = uibutton(app.TemperatureTab, 'push');
            app.StepButton1d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton1d.Enable = 'off';
            app.StepButton1d.Text = {'>'; '1 d'};
            app.StepButton1w = uibutton(app.TemperatureTab, 'push');
            app.StepButton1w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton1w.Enable = 'off';
            app.StepButton1w.Text = {'>'; '1 w'};
            app.CropTab = uitab(app.TabGroup);
            app.CropTab.Title = 'Crop';
            app.UICropAxes = uiaxes(app.CropTab);
            title(app.UICropAxes, 'Crop Gauge')
            xlabel(app.UICropAxes, 'Hour')
            ylabel(app.UICropAxes, 'Biomass (kg)')
            app.StepButton2w = uibutton(app.CropTab, 'push');
            app.StepButton2w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton2w.Enable = 'off';
            app.StepButton2w.Text = {'>'; '1 w'};
            app.StepButton2d = uibutton(app.CropTab, 'push');
            app.StepButton2d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton2d.Enable = 'off';
            app.StepButton2d.Text = {'>'; '1 d'};
            app.StepButton2h = uibutton(app.CropTab, 'push');
            app.StepButton2h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton2h.Enable = 'off';
            app.StepButton2h.Text = {'>'; '1 hr'};
            app.WaterTab = uitab(app.TabGroup);
            app.WaterTab.Title = 'Water';
            app.UIWaterAxes = uiaxes(app.WaterTab);
            title(app.UIWaterAxes, 'Water Gauge')
            xlabel(app.UIWaterAxes, 'Hour')
            ylabel(app.UIWaterAxes, 'Volume (m^3)')
            app.StepButton3w = uibutton(app.WaterTab, 'push');
            app.StepButton3w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton3w.Enable = 'off';
            app.StepButton3w.Text = {'>'; '1 w'};
            app.StepButton3d = uibutton(app.WaterTab, 'push');
            app.StepButton3d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton3d.Enable = 'off';
            app.StepButton3d.Text = {'>'; '1 d'};
            app.StepButton3h = uibutton(app.WaterTab, 'push');
            app.StepButton3h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton3h.Enable = 'off';
            app.StepButton3h.Text = {'>'; '1 hr'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end